classdef Control_robot_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Theta1SliderLabel     matlab.ui.control.Label
        Theta1Slider          matlab.ui.control.Slider
        Theta2SliderLabel     matlab.ui.control.Label
        Theta2Slider          matlab.ui.control.Slider
        Theta3SliderLabel     matlab.ui.control.Label
        Theta3Slider          matlab.ui.control.Slider
        UIAxes                matlab.ui.control.UIAxes
        Theta1EditFieldLabel  matlab.ui.control.Label
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta2EditFieldLabel  matlab.ui.control.Label
        Theta2EditField       matlab.ui.control.NumericEditField
        StartButton           matlab.ui.control.Button
        Theta3EditFieldLabel  matlab.ui.control.Label
        Theta3EditField       matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        ZEditField            matlab.ui.control.NumericEditField
        ResponseLabel         matlab.ui.control.Label
        DegreeLabel           matlab.ui.control.Label
        DegreeLabel_2         matlab.ui.control.Label
        DegreeLabel_3         matlab.ui.control.Label
        cmLabel               matlab.ui.control.Label
        cmLabel_2             matlab.ui.control.Label
        cmLabel_3             matlab.ui.control.Label
    end


    properties (Access = private)
        theta1 = 0;
        theta2 = 0;
        theta3 = 0;
        
        newTheta1 = 0;
        newTheta2 = 0;
        newTheta3 = 0;
        
        % Transformation matrices (initially identity matrices)
        T0_0 = eye(4);
        T0_1;
        T0_2;
        T0_3;
        
        % Joint
        joint0;
        joint1;
        joint2;
        joint3;
    end
    
    properties (Constant)
        r1 = 10;    % Link radius
        r2 = 15;    % Joint radius
        r3 = 25;    % Base radius
        h_base = 20;        % Base height
        h_joint = 40;       % Joint height
        
        % Link, Joints lengths or distances
        d1 = 80;
        a2 = 60;
        a3 = 50;
    end

    methods (Static)
        %% Tranformation matrices function
        function matrixDH = T(d, theta, a, alpha)
        matrixDH = [cos(theta) -cos(alpha)*sin(theta) sin(alpha)*sin(theta) a*cos(theta)
             sin(theta) cos(alpha)*cos(theta) -sin(alpha)*cos(theta) a*sin(theta)
                 0          sin(alpha)             cos(alpha)           d
                 0              0                      0                1 ];
        end
    end
    
    methods (Access = private) 
        %% Display 3D Robot
        function path = mlsDisplayRobot(app, path)
            % Plot the robot in app UIAxes
            cla(app.UIAxes);  % Clear previous plot
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
%             axis(app.UIAxes, 'equal');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            title(app.UIAxes, 'Robotics Anh Vu 3-Dof');
            xlim(app.UIAxes, [-200, 200]);
            ylim(app.UIAxes, [-200, 200]);
            zlim(app.UIAxes, [0, 200]);
            
            % Tao khoi O_0
            [x1, y1, z1] = cylinder(app.r3);        % Base O_0
            z1 = z1 * app.h_base;
            [x2, y2, z2] = cylinder(app.r2);        % Joint O_0
            z2 = z2 * app.h_joint;
            [x3, y3, z3] = cylinder(app.r1);        % Link O_0
            z3 = z3 * app.d1;
            
            % Tao khoi O_1
            [x4, y4, z4] = cylinder(app.r2);        % Joint O_1
            z4 = z4 * app.h_joint;
            [x5, y5, z5] = cylinder(app.r1);        % Link O_1
            
            % Tao khoi O_2
            [x6, y6, z6] = cylinder(app.r2);        % Joint O_2
            z6 = z6 * app.h_joint;
            [x7, y7, z7] = cylinder(app.r1);        % Link O_2
            
            % Xoay 90 do => link vuong goc joint
            rotY = [cos(pi/2)  0 sin(pi/2) 0; 
                    0          1 0         0; 
                    -sin(pi/2) 0 cos(pi/2) 0; 
                    0          0 0         1];
            
            % Transformation matrices
            app.T0_0 = eye(4);
            app.T0_1 = T(app.d1, app.theta1, 0, pi/2);
            T1_2 = T(0, app.theta2, app.a2, 0);
            T2_3 = T(0, app.theta3, app.a3, 0);
            
            app.T0_2 = app.T0_1 * T1_2;
            app.T0_3 = app.T0_2 * T2_3;
             
            app.joint0 = app.T0_0(1:3, 4);  % Base origin
            app.joint1 = app.T0_1(1:3, 4); % Joint 1
            app.joint2 = app.T0_2(1:3, 4); % Joint 2
            app.joint3 = app.T0_3(1:3, 4); % Joint 3 (end effector)
            
            % Joint 1
            for j = 1:length(x2)  
                for i = 1:2  
                    Pos2 = [x2(i, j); y2(i, j); z2(i, j) - app.h_joint/2 ; 1];          % Joint O_0 
                    Pos4 = app.T0_1 * Pos2;                                             % Joint O_1
                    x4(i, j) = Pos4(1);
                    y4(i, j) = Pos4(2);
                    z4(i, j) = Pos4(3);
                end
            end
            
            % Link 1
            for j = 1:length(x3)  
                for i = 1:2  
                    Pos3 = [x3(i, j); y3(i, j); -z3(i, j)*app.a2/app.d1; 1];            % Link O_0
                    Pos5 = app.T0_2 * rotY * Pos3;                                      % Link O_1
                    x5(i, j) = Pos5(1);
                    y5(i, j) = Pos5(2);
                    z5(i, j) = Pos5(3);
                end
            end
            
            % Joint 2
            for j = 1:length(x2)  
                for i = 1:2  
                    Pos2 = [x2(i, j); y2(i, j); z2(i, j) - app.h_joint/2 ; 1];          % Joint O_0 
                    Pos6 = app.T0_2 * Pos2;                                             % Joint O_2
                    x6(i, j) = Pos6(1);
                    y6(i, j) = Pos6(2);
                    z6(i, j) = Pos6(3);
                end
            end
            
            % Link 2
            for j = 1:length(x3)  
                for i = 1:2  
                    Pos3 = [x3(i, j); y3(i, j); -z3(i, j)*app.a3/app.d1; 1];            % Link O_0
                    Pos7 = app.T0_3 * rotY * Pos3;                                      % Link O_2
                    x7(i, j) = Pos7(1);
                    y7(i, j) = Pos7(2);
                    z7(i, j) = Pos7(3);
                end
            end
            
            % Draw the robot using surf and fill3, just as in the original code
            % Base
            surf(app.UIAxes, x1, y1, z1, 'EdgeColor', 'none', 'FaceColor', 'r'); 
            fill3(app.UIAxes, x1(1,:), y1(1,:), z1(1,:), 'r');
            fill3(app.UIAxes, x1(2,:), y1(2,:), z1(2,:), 'r');
            
            % Joint 0 - Blue
            surf(app.UIAxes, x2, y2, z2, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x2(1,:), y2(1,:), z2(1,:), 'b');
            fill3(app.UIAxes, x2(2,:), y2(2,:), z2(2,:), 'b');
            
            % Link 0 - Yellow
            surf(app.UIAxes, x3, y3, z3, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x3(1,:), y3(1,:), z3(1,:), 'y');
            fill3(app.UIAxes, x3(2,:), y3(2,:), z3(2,:), 'y');
            
            % Joint 1 - Blue
            surf(app.UIAxes, x4, y4, z4, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x4(1,:), y4(1,:), z4(1,:), 'b');
            fill3(app.UIAxes, x4(2,:), y4(2,:), z4(2,:), 'b');
            
            % Link 1 - Yellow
            surf(app.UIAxes, x5, y5, z5, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x5(1,:), y5(1,:), z5(1,:), 'y');
            fill3(app.UIAxes, x5(2,:), y5(2,:), z5(2,:), 'y');
            
            % Joint 2 - Blue
            surf(app.UIAxes, x6, y6, z6, 'EdgeColor', 'none', 'FaceColor', 'b');
            fill3(app.UIAxes, x6(1,:), y6(1,:), z6(1,:), 'b');
            fill3(app.UIAxes, x6(2,:), y6(2,:), z6(2,:), 'b');
            
            % Link 2 - Yellow
            surf(app.UIAxes, x7, y7, z7, 'EdgeColor', 'none', 'FaceColor', 'y');
            fill3(app.UIAxes, x7(1,:), y7(1,:), z7(1,:), 'y');
            fill3(app.UIAxes, x7(2,:), y7(2,:), z7(2,:), 'y');
            
            app.XEditField.Value = app.joint3(1);
            app.YEditField.Value = app.joint3(2);
            app.ZEditField.Value = app.joint3(3);
            
            % Plot joint positions (O0, O1, O2, O3)
            plot3(app.UIAxes, app.joint0(1), app.joint0(2), app.joint0(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint0(1), app.joint0(2), app.joint0(3), 'O_0', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, app.joint1(1), app.joint1(2), app.joint1(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint1(1), app.joint1(2), app.joint1(3), 'O_1', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, app.joint2(1), app.joint2(2), app.joint2(3), 'ro', 'MarkerSize', 2, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint2(1), app.joint2(2), app.joint2(3), 'O_2', 'VerticalAlignment', 'bottom');
            plot3(app.UIAxes, app.joint3(1), app.joint3(2), app.joint3(3), 'ro', 'MarkerSize', 7, 'MarkerFaceColor', 'r');
            text(app.UIAxes, app.joint3(1), app.joint3(2), app.joint3(3), 'O_3', 'VerticalAlignment', 'bottom');
            
            [~, n] = size(path);
            % append path
            path(1,n+1) = app.joint3(1);
            path(2,n+1) = app.joint3(2);
            path(3,n+1) = app.joint3(3);
            % draw path
            x = path(1,:);
            y = path(2,:);
            z = path(3,:);
            plot3(app.UIAxes,x,y,z,'LineWidth',2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global path;
            rotate3d(app.UIAxes, 'on');
            path = app.mlsDisplayRobot(path);
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            app.newTheta1 = app.Theta1Slider.Value;
            app.Theta1EditField.Value = app.newTheta1;
            app.newTheta1 = deg2rad(app.newTheta1);
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            app.newTheta2 = app.Theta2Slider.Value;
            app.Theta2EditField.Value = app.newTheta2;
            app.newTheta2 = deg2rad(app.newTheta2);
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            app.newTheta3 = app.Theta3Slider.Value;
            app.Theta3EditField.Value = app.newTheta3;
            app.newTheta3 = deg2rad(app.newTheta3);
        end

        % Button pushed function: StartButton
        function FowardStartPushed(app, event)
            global path;
            % Chuyen dong theo theta1
            if app.theta1 ~= app.newTheta1
                theta1Array = linspace(app.theta1,app.newTheta1,20);
                for i = 1:20
                    app.theta1 = theta1Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta2
            if app.theta2 ~= app.newTheta2
                theta2Array = linspace(app.theta2,app.newTheta2,20);
                for i = 1:20
                    app.theta2 = theta2Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
            % Chuyen dong theo theta3
            if app.theta3 ~= app.newTheta3
                theta3Array = linspace(app.theta3,app.newTheta3,20);
                for i = 1:20
                    app.theta3 = theta3Array(i);
                    path = app.mlsDisplayRobot(path);
                    pause(0.1);
                end
            end
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.newTheta1 = app.Theta1EditField.Value;
            app.newTheta1 = deg2rad(app.newTheta1);
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            app.newTheta2 = app.Theta2EditField.Value;
            app.newTheta2 = deg2rad(app.newTheta2);
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            app.newTheta3 = app.Theta3EditField.Value;
            app.newTheta3 = deg2rad(app.newTheta3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clearvars -global path;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-90 270];
            app.Theta2Slider.MajorTicks = [-90 -45 0 45 90 135 180 225 270];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robotics')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-200 200];
            app.UIAxes.ZLim = [0 200];
            app.UIAxes.ZTick = [-200 -100 0 100 200];
            app.UIAxes.ZTickLabel = {'-200'; '-100'; '0'; '100'; '200'};
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @FowardStartPushed, true);
            app.StartButton.Text = 'Start';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResponseLabel = uilabel(app.UIFigure);
            app.ResponseLabel.Text = 'Response';
            app.DegreeLabel = uilabel(app.UIFigure);
            app.DegreeLabel.Text = 'Degree';
            app.DegreeLabel_2 = uilabel(app.UIFigure);
            app.DegreeLabel_2.Text = 'Degree';
            app.DegreeLabel_3 = uilabel(app.UIFigure);
            app.DegreeLabel_3.Text = 'Degree';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = 'cm';
            app.cmLabel_2 = uilabel(app.UIFigure);
            app.cmLabel_2.Text = 'cm';
            app.cmLabel_3 = uilabel(app.UIFigure);
            app.cmLabel_3.Text = 'cm';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Control_robot_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end